classdef VoCAT_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoCATUIFigure                  matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        Image                          matlab.ui.control.Image
        AnalysisResultsAverageDataLabel  matlab.ui.control.Label
        PreviewButton_2                matlab.ui.control.Button
        AnalysisResultsFigureLabel     matlab.ui.control.Label
        SaveResultButton               matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        SegmentResultLabel             matlab.ui.control.Label
        PreviewButton                  matlab.ui.control.Button
        VesselAnalysisButton           matlab.ui.control.Button
        SegmentButton                  matlab.ui.control.Button
        SegmentationtoolDropDown       matlab.ui.control.DropDown
        SegmentationtoolDropDownLabel  matlab.ui.control.Label
        ParametersettingLabel          matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        ScalingperPixelEditField_3     matlab.ui.control.NumericEditField
        ScalingperPixelEditField_2     matlab.ui.control.NumericEditField
        SaveTextEditField              matlab.ui.control.EditField
        SaveTextEditFieldLabel         matlab.ui.control.Label
        LengthThresholdEditField       matlab.ui.control.NumericEditField
        LengthThresholdEditFieldLabel  matlab.ui.control.Label
        PointsPerBranchEditField       matlab.ui.control.NumericEditField
        PointsPerBranchEditFieldLabel  matlab.ui.control.Label
        RadiusPrecisionEditField       matlab.ui.control.NumericEditField
        RadiusPrecisionEditFieldLabel  matlab.ui.control.Label
        SmoothingRepetitionsEditField  matlab.ui.control.NumericEditField
        SmoothingRepetitionsEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorEditField    matlab.ui.control.NumericEditField
        DownsamplingFactorEditFieldLabel  matlab.ui.control.Label
        ScalingperPixelEditField       matlab.ui.control.NumericEditField
        ScalingperPixelEditFieldLabel  matlab.ui.control.Label
        LogTextArea                    matlab.ui.control.TextArea
        LogTextAreaLabel               matlab.ui.control.Label
        DataLoadButton                 matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        HistogramsMenu                 matlab.ui.container.Menu
        TortuosityMenu                 matlab.ui.container.Menu
        RadiusMenu                     matlab.ui.container.Menu
        LengthMenu                     matlab.ui.container.Menu
        EccentricityMenu               matlab.ui.container.Menu
        SkeletonizationMenu            matlab.ui.container.Menu
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        vol;
        pathtoimg;
        extension;
        DLnet;
        VoCAT_Data;
        StrArray;
        branchdata;
        bw;% Description
        Correctbw;
        file_name;
        ResDataTable;
        tot_branches;
    end
    
    methods (Access = private)
        function logRefresh_func(app,StrArrayNew)
        	%刷日志
        	if length(app.StrArray)>=500
        	    app.StrArray={};
        	end
        	app.StrArray=[app.StrArray;{StrArrayNew}];
        	app.LogTextArea.Value=app.StrArray;
            drawnow
        end        
        
        function Result_Show(app,~)
            % T1 = load('E:\IMG_暂存\testtif\TrainingData\VoCAT_Data.mat');
            % app.VoCAT_Data = T1.VoCAT_Data;
            columnNames = {'Name', 'mRad','Branchpoints','Endpoints','Connectivity ratio','mRad_REAVER', 'mlen', 'mTort','mTort_New', 'mEcc', 'volFrac', 'approxAlat', 'realAlat', 'S_over_V', 'numSubN','GoodValue','NNZ(BW)', 'NUMEL(BW)','Fractal dimension'};
            varTypes = {'string','double','single','double','double','double','double','double','double','double','double','double','double','double','double','double','double','double','double'};
            dataTable = table('Size', [1 19], 'VariableTypes',varTypes,'VariableNames', columnNames);
            mTort_New = mean(app.VoCAT_Data.branchdata.Tort_new,'omitnan');
            isGOOD = app.VoCAT_Data.branchdata.isGood;
            logicalArray = isGOOD > 0;
            count = sum(logicalArray);
            GoodValue = count/length(isGOOD);
            bp = app.VoCAT_Data.skel.bp;
            ep = app.VoCAT_Data.skel.ep;
            Num_bp = size(regionprops3(bp,'Volume'),1);
            Num_ep = size(regionprops3(ep,'Volume'),1);
            dataTable{1,1} = string(app.file_name);
            dataTable{1,2} = app.VoCAT_Data.mRad;
            dataTable{1,3} = Num_bp;
            dataTable{1,4} = Num_ep;
            dataTable{1,5} = app.VoCAT_Data.Connectivity_Ratio;
            dataTable{1,6} = app.VoCAT_Data.mRad_REAVER;
            dataTable{1,7} = app.VoCAT_Data.mLen;
            dataTable{1,8} = app.VoCAT_Data.mTort;
            dataTable{1,9} = mTort_New;
            dataTable{1,10} = app.VoCAT_Data.mEcc;
            dataTable{1,11} = app.VoCAT_Data.volFrac;
            dataTable{1,12} = app.VoCAT_Data.approxAlat;
            dataTable{1,13} = app.VoCAT_Data.realAlat;
            dataTable{1,14} = app.VoCAT_Data.S_over_V;
            dataTable{1,15} = app.VoCAT_Data.numSubN;
            dataTable{1,16} = GoodValue;
            dataTable{1,17} = nnz(app.VoCAT_Data.Correctbw);
            dataTable{1,18} = numel(app.VoCAT_Data.Correctbw);
            FD = app.FDcal();
            dataTable{1,19} = FD;
            app.UITable.Data = dataTable;
            app.ResDataTable = dataTable;
        end
        
        function results = data_corrector(app,data)
            pxdens = [app.ScalingperPixelEditField.Value app.ScalingperPixelEditField_2.Value app.ScalingperPixelEditField_3.Value];
            V = data;
            [h,w,l] = size(V);
            % Creating the meshgrid 网格
            [x,y,z] = meshgrid(1:h,1:w,1:l);
            % 体素尺寸调整，如果垂直方向的像素密度不同，则体素表示一个平行六面体，并将其转换为一个具有线性插值的立方体。
            if pxdens(3)~=pxdens(1)
                newvol = [];
                for slh = linspace(1,l,(l-1)*pxdens(3)/pxdens(1))
                    s = slice(x,y,z,double(V),[],[],slh,'linear');
                    newvol = cat(3,newvol,(s.CData)');
                    s.Visible = false;
                    disp(slh);
                end
                V = smooth3(newvol,'gaussian');
            end


            zT = ceil(size(V,3)/8)*8;

            results = imresize3(V,[h w zT]);
        end
        
        function HausdorffDimension = FDcal(app)
            ske = app.VoCAT_Data.skel.sk;
            flattened = max(ske, [],3);
            p1 = flattened;
            p1 = uint8(p1);
            p1(p1==1) = 255;
            e2 = p1;
            Nx = size(e2,1);
            Ny = size(e2,2);
            Num_steps = floor(Nx./5);
            for numBlocks = 1:Num_steps

                sizeBlocks_x = floor(Nx./numBlocks);
                sizeBlocks_y = floor(Ny./numBlocks);

                flag = zeros(numBlocks,numBlocks);
                for i = 1:numBlocks
                    for j = 1:numBlocks
                        xStart = (i-1)*sizeBlocks_x + 1;
                        xEnd   = i*sizeBlocks_x;

                        yStart = (j-1)*sizeBlocks_y + 1;
                        yEnd   = j*sizeBlocks_y;

                        block = e2(xStart:xEnd, yStart:yEnd);

                        flag(i,j) = any(block(:)); %mark this if ANY part of block is true
                    end
                end
                boxCount = nnz(flag);
                table(numBlocks,1)    = numBlocks;
                table(numBlocks,2)    = boxCount;
            end

            % from the above table of discrete points, take a line of best fit and plot
            % the raw data (ro) and line of best fit (r-)
            x      = table(:,1);    % x is numBlocks
            y      = table(:,2);    % y is boxCount

            %p       = polyfit(x,y,1);
            %BestFit = polyval(p,x);

            % calculate Hausdorff Dimension
            x2 = log(x);
            y2 = log(y);

            p2       = polyfit(x2,y2,1);
            %BestFit2 = polyval(p2,x2);

            HausdorffDimension = p2(:,1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            req = {'Image Processing Toolbox', 'Curve Fitting Toolbox', ...
                'Computer Vision Toolbox','Deep Learning Toolbox'};
            if ~check_req(req)
                strshow = ['Requirements:',string(req)];
                strshow = char(strjoin(strshow, ','));
                logRefresh_func(app,strshow);
                logRefresh_func(app,'ERROR: You are missing some toolboxes that are required for the App, please install first!');
            end
        end

        % Button pushed function: DataLoadButton
        function DataLoadButtonPushed(app, event)
            % load the Bio-Formats library into the MATLAB environment
            % status = bfCheckJavaPath();
            % assert(status, ['Missing Bio-Formats library. Either add bioformats_package.jar '...
            %     'to the static Java path or add it to the Matlab path.']);
            [file, path] = uigetfile({'*.tif';'*.tiff'},...
                          'Chose a tif or tiff file');
            filename = [path file];
            app.file_name = file;
            if isequal(path, 0) || isequal(file, 0)
                errordlg('No File Selected','Error');
            else
                logRefresh_func(app,'Loading');
                volume = tiffreadVolume(filename);
                % app.Slider.MajorTicks = 1:size(app.vol,3);
                % app.NLabel.Text = string(size(app.vol,3));
                % cellArray = cell(1, size(app.vol,3)); % 创建1行8列的元胞数组
                % for i = 1:size(app.vol,3)
                %     cellArray{i} = num2str(i); % 将数字转换为字符并赋值
                % end
                % app.Slider.MajorTickLabels = cellArray;
                % app.Slider.MinorTicks = 1:size(app.vol,3);
                % splpath = strsplit(volume{1}{2},".");
                % app.pathtoimg = splpath(1);
                % app.extension = strcat(".",splpath(2));
                app.vol = volume;
                logRefresh_func(app,filename);
                logRefresh_func(app,'Creating 2D Max Projection');
                flattened = max(app.vol, [],3);
                app.VoCAT_Data.raw = app.vol;
                app.VoCAT_Data.flat = flattened;
                % Saving the 2D projection as 'filename_flat.bmp'
                truec = cat(3, flattened,flattened,flattened);
                figure;imshow(truec);title('2D Max Projection'); 
                %imwrite(mat2gray(truec),[app.pathtoimg '.bmp']);
                t2 = figure;sliceViewer(app.vol);
                set(t2,'name','Original Data');
                app.SegmentButton.Enable = 'on';
                app.VesselAnalysisButton.Enable = 'on';
                app.PreviewButton_2.Enable = 'on';
            end
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            if app.SegmentationtoolDropDown.Value == 'Deep learning'
                downfactor = app.DownsamplingFactorEditField.Value;
                
                % resize to segmentation
                [x, y, z] = size(app.vol);
                xT = findClosestMultiple(x);
                yT = findClosestMultiple(y);
                zT = round(z/8)*8;
                DataR = imresize3(app.vol,[xT yT zT]);
                rescfact = size(DataR);
                vres = DataR;
                Segmentres = zeros(rescfact);
                logRefresh_func(app,'Perform Deep learning segmentation');
                f = waitbar(0,'Loading network...');
                %pause(.3)
                % 获取打包文件的临时解压路径
                [file, path] = uigetfile({'*.mat'},...
                          'Chose a segment network file');
                filename = [path file];
                if isequal(path, 0) || isequal(file, 0)
                    errordlg('No network Selected','Error');
                    close(f);
                else
                    temp = load(filename);
                    %temp = load('./Trained Network/LUnet.mat');
                    app.DLnet = temp.DP_net;
                    waitbar(.33,f,'Segmenting...');
                    for i=0:128:(rescfact(1)-128)
                        for j=0:128:(rescfact(2)-128)
                            for k=0:8:(rescfact(3)-8)
                                Segmentres((i+1):(i+128), (j+1):(j+128), (k+1):(k+8)) = ...
                                    semanticseg(vres((i+1):(i+128), (j+1):(j+128), (k+1):(k+8)),app.DLnet);
                            end
                        end
                    end
                    Segmentres(Segmentres == 1) = 0;
                    Segmentres(Segmentres == 2) = 255;
                    app.bw = logical(Segmentres);
                    clear temp DLnet rescfact Segmentres vres i j k
                end
                
            else
                msgbox('No DP network founded, Please use AC','Note');
                Vol = app.vol;
                mask = zeros(size(Vol));
                f = waitbar(0,'Data smoothing...');
                %pause(.3)
                smoothing_repeat = app.SmoothingRepetitionsEditField.Value;
                for i=1:smoothing_repeat
                    Vol = smooth3(Vol);
                end

                for seedLevel = 1:size(Vol,3)
                    % The mask is initialized by comparing all pixels in the brighter layer
                    % with a threshold value, obtaining a binary image that is the
                    % 2D segmentation (inaccurate) of this layer.
                    seed = Vol(:,:,seedLevel) > mean(Vol,'all');
                    % The mask gets thickened by 10 pixels and then furtherly refined
                    % with a moving average 2D filter of arbitrary size of 10x10.
                    seed_thick = bwmorph(seed, 'thicken', 10);
                    N = 3;
                    kernel = ones(N, N) / N^2;
                    seed_thick = conv2(double(seed_thick), kernel, 'same');

                    % The result of the moving average is of type double.In order to
                    % reconvert it to type 'logical' it must be compared with a
                    % threshold value that depends on convolution kernel size
                    seed_thick = seed_thick > N/10;
                    mask(:,:,seedLevel) = seed_thick;
                end
                waitbar(.33,f,'Segmenting...');
                pause(.3)
                logRefresh_func(app,'Perform Active contour segmentation--------------------------------->');
                app.bw = activecontour(Vol,mask,100);
            end
            % Smoothing edges
            smoothing_repeat = app.SmoothingRepetitionsEditField.Value;
            for i=1:smoothing_repeat
                app.bw = smooth3(app.bw);
            end
            se = strel('disk', 3);  % Size changeable
            closedImg = imclose(app.bw, se);
            app.bw = logical(closedImg);

            [h,w,l] = size(app.vol);
            app.bw = imresize3(app.bw,[h,w,l]);

            
            waitbar(.67,f,'Voxel correcting...');
            %V = data_corrector(app,V);
            app.bw = app.bw(1:downfactor:end, 1:downfactor:end, 1:downfactor:end);%降采样
            pxdens = [app.ScalingperPixelEditField.Value app.ScalingperPixelEditField_2.Value app.ScalingperPixelEditField_3.Value];
            [h,w,l] = size(app.bw);
            % Creating the meshgrid
            [x,y,z] = meshgrid(1:w,1:h,1:l);
            if pxdens(3)~=pxdens(1)
                newvol = [];
                for slh = linspace(1,l,(l-1)*pxdens(3)/pxdens(1))
                    fig = figure('Visible','off');
                    s = slice(x,y,z,double(app.bw),[],[],slh,'linear');
                    newvol = cat(3,newvol,(s.CData)');
                    waitbar(.8,f,strcat('Voxel adjusting...',string(slh)));
                end
                app.bw = smooth3(newvol,'gaussian');
            end
            app.Correctbw = logical(app.bw);
            app.VoCAT_Data.Correctbw = app.Correctbw;

            clear newvol h w l slh s fig se
            logRefresh_func(app,'Segment Finished');
            waitbar(1,f,'Finishing!');
            pause(1)
            close(f)
            if nnz(app.bw)/numel(app.bw) < 0.05
                logRefresh_func(app,'Waning: Bad segmentation, please chose other segmentation!')
            else
                app.PreviewButton.Enable = "on";
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            volumeViewer(app.Correctbw);
        end

        % Close request function: VoCATUIFigure
        function VoCATUIFigureCloseRequest(app, event)
            delete(app)
            close all;
            clear all;
        end

        % Button pushed function: VesselAnalysisButton
        function VesselAnalysisButtonPushed(app, event)
            pxdens = [app.ScalingperPixelEditField.Value app.ScalingperPixelEditField_2.Value app.ScalingperPixelEditField_3.Value];
            %骨架化
            logRefresh_func(app,'Computing the skeleton');
            f = waitbar(0,'Skeletonizing','Name','Vessel data processing',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            setappdata(f,'canceling',0);
            sk = Skeleton3D(app.Correctbw);
            % For some reason Skeleton3D swaps the X and Y dimensions
            sk = permute(sk, [2 1 3]);
            app.VoCAT_Data.skel.sk = sk;
            %寻找分支点
            bpoints = bwmorph3(sk,'branchpoints');
            epoints = bwmorph3(sk,'endpoints');
            app.VoCAT_Data.skel.bp = bpoints;
            app.VoCAT_Data.skel.ep = epoints;
            app.VoCAT_Data.NumBranchpoints = size(regionprops3(bpoints,'Volume'),1);
            app.VoCAT_Data.NumEndpoints = size(regionprops3(epoints,'Volume'),1);
            app.VoCAT_Data.Connectivity_Ratio = app.VoCAT_Data.NumBranchpoints/app.VoCAT_Data.NumEndpoints;
            clear bpoints epoints
            %分支点分类
            [adj, node, link] = Skel2Graph3D(sk,app.LengthThresholdEditField.Value);
            [h,w,l] = size(app.Correctbw);
            app.tot_branches = numel(link);
            
            app.branchdata = table(0,{0},{0},{0},[0,0,0],[0,0,0],{0},...
                'VariableNames', {'Num','xPath','yPath','zPath','From','To',...
                'Interp'});
            for b=1:app.tot_branches
                path_idx = link(b).point;
                [path_x, path_y, path_z] = ind2sub([h,w,l],path_idx);
                interp = cscvn([path_x; path_y; path_z]);
                x_from = node(link(b).n1).comx;
                y_from = node(link(b).n1).comy;
                z_from = node(link(b).n1).comz;
                x_to = node(link(b).n2).comx;
                y_to = node(link(b).n2).comy;
                z_to = node(link(b).n2).comz;
                from = [x_from,y_from,z_from];
                to = [x_to,y_to,z_to];
                toAdd = {b, path_x, path_y, path_z, from, to,interp};
                app.branchdata = cat(1, app.branchdata, toAdd);
            end
            app.branchdata(1,:) = [];

            G = graph(adj);
            for n = 1:numel(node)
                G.Nodes.x(n) = node(n).comx;
                G.Nodes.y(n) = node(n).comy;
                G.Nodes.z(n) = node(n).comz;
                G.Nodes.subN(n) = 0;
            end

            numsn = 1;
            while any(G.Nodes.subN == 0)
                non_lab = find(G.Nodes.subN==0);
                G = floodgraph(G,non_lab(1),numsn);
                numsn=numsn+1;
            end
            app.VoCAT_Data.skel.graph = G;
            app.VoCAT_Data.branchdata = app.branchdata;
            sk = zeros(h,w,l);
            for i=1:app.tot_branches
                for j=1:numel(app.branchdata.xPath{i})
                    sk(app.branchdata.xPath{i}(j),app.branchdata.yPath{i}(j),app.branchdata.zPath{i}(j)) = 1;
                end
            end

            clear adj b from G i ...
                interp j link node path_idx path_x path_y path_z ...
                to toAdd non_lab x_from x_to y_from y_to z_from z_to
            %血管参数提取
            logRefresh_func(app,'Morphological measurements');
            errors = 0;

            steps = app.tot_branches;
            for b=1:app.tot_branches
                if getappdata(f,'canceling')
                    break
                end
                % LENGTH
                xyzpath = [app.branchdata.xPath{b};app.branchdata.yPath{b};app.branchdata.zPath{b}];
                app.branchdata.Len(b) = sum(sqrt(sum(diff(xyzpath,1,2).^2)));

                % TORTUOSITY [as T = Dist/L] 弯曲度
                d = sqrt(sum((app.branchdata.From(b,:)-app.branchdata.To(b,:)).^2));
                app.branchdata.Tort(b) = constrain(app.branchdata.Len(b)/d,[1 Inf]);

                % TORTUOSITY [new approach as variance of the mean angle]
                xyzdir = diff(xyzpath,1,2);
                theta = zeros(1,length(xyzdir));
                for i=1:length(xyzdir)-2
                    theta(i) = angle3(xyzdir(:,i), xyzdir(:,i+1));
                end
                thetanorm = theta-mean(theta);
                app.branchdata.Tort_new(b) = std(thetanorm);

                J = linspace(0,1,app.RadiusPrecisionEditField.Value+2);
                J = J(2:end-1);
                r = NaN*ones(app.RadiusPrecisionEditField.Value,1);
                a = r;
                e = r;
                o = r;
                k = 1;
                for j=J
                    % RADIUS
                    intp = app.branchdata.Interp{b};
                    tmax = intp.breaks(end);
                    basep = ppval(intp,tmax*j);
                    nextp = ppval(intp,tmax*j+tmax/app.PointsPerBranchEditField.Value);
                    normal = basep-nextp;
                    normal = normal/norm(normal);

                    % Slicing along the normal direction of the vessel 沿着血管的法线方向切片
                    try
                        [sl,xs,ys,zs] = obliqueslice(double(app.Correctbw),round(basep)',normal',...
                            'Method','nearest');

                        % Looking for the point of the skeleton on the slice 在切片上查找骨架点
                        skp = abs(xs-basep(1))<1 & abs(ys-basep(2))<1 & abs(zs-basep(3))<1;

                        %             NEW APPROACH WITH RECURSION 采用递归的新方法
                        [x_skp, y_skp] = ind2sub(size(skp),find(skp));
                        truesec = floodimg(sl,[round(mean(x_skp)),round(mean(y_skp))])==2;

                        area = nnz(truesec);
                        r(k) = sqrt(area/pi);
                        if r(k)==0
                            r(k) = 1;
                        end
                    catch
                        errors = errors+1;
                        continue;
                    end
                    % LATERAL SURFACE AREA 横截面积
                    a(k)= 2*pi*r(k)*app.branchdata.Len(b)/numel(J);

                    % ECCENTRICITY and ORIENTATION 离心率和方向
                    currecc = regionprops(truesec,'MajorAxisLength','MinorAxisLength','Orientation');
                    if ~isempty(currecc)
                        e(k) = sqrt(1-currecc.MinorAxisLength^2/currecc.MajorAxisLength^2);
                        o(k) = currecc.Orientation;
                    end
                    k = k+1;
                end
                app.branchdata.Rad(b) = mean(r,'omitnan');
                app.branchdata.Alat(b) = mean(a,'omitnan');
                app.branchdata.Eccent(b) = mean(e,'omitnan');
                app.branchdata.Orientat(b) = mean(o,'omitnan');
                % Goodness condition of the vessel: Length > Radius*3 (arbitrary) 血管的良好条件：长度 > 半径*3（任意）
                app.branchdata.isGood(b) = app.branchdata.Len(b) > app.branchdata.Rad(b)*3;
                % showinfo = strcat(string(b),"/",string(tot_branches)," branches analyzed");
                % logRefresh_func(app,showinfo);
                waitbar(b/steps,f,strcat(string(b),"/",string(app.tot_branches)," branches analyzed"))
            end
            delete(f);
            % Scaling the metrics based on the downsampling and conversion to
            % micrometers 根据下采样和转换为微米来缩放度量标准
            downfactor = app.DownsamplingFactorEditField.Value;
            app.branchdata.Rad = app.branchdata.Rad*pxdens(1)*downfactor;
            app.branchdata.Len = app.branchdata.Len*pxdens(1)*downfactor;
            app.branchdata.Alat = app.branchdata.Alat*(pxdens(1)^2)*(downfactor^2);
            % CALCULATING THE REAL LATERAL AREA 计算实际横向面积
            realAlat = nnz(bwmorph3(app.Correctbw,'remove'));
            %% 保存结果
            app.VoCAT_Data.branchdata = app.branchdata;
            app.VoCAT_Data.mRad = mean(app.branchdata.Rad(:),'omitnan');
            bwd = bwdist(~app.Correctbw);
            bwdsk = bwd; bwdsk(sk==0) = 0;
            app.VoCAT_Data.mRad_REAVER = mean(bwdsk(bwdsk>0),'all')*pxdens(1);
            app.VoCAT_Data.mLen = mean(app.branchdata.Len(:),'omitnan');
            app.VoCAT_Data.mTort = mean(app.branchdata.Tort(:),'omitnan');
            app.VoCAT_Data.mEcc = mean(app.branchdata.Eccent(:),'omitnan');
            app.VoCAT_Data.volFrac = nnz(app.Correctbw)/(numel(app.Correctbw));
            app.VoCAT_Data.approxAlat = sum(app.branchdata.Alat(:),'omitnan'); % omitnan 自动忽略 NaN
            app.VoCAT_Data.realAlat = realAlat*pxdens(1)^2*downfactor^2;
            app.VoCAT_Data.S_over_V = app.VoCAT_Data.realAlat/((nnz(app.Correctbw))*pxdens(1)^3*downfactor^3);
            app.VoCAT_Data.numSubN = numsn;
            for i = 1:numsn
                if nnz(app.VoCAT_Data.skel.graph.Nodes.subN == i) == 1
                    app.VoCAT_Data.numSubN = app.VoCAT_Data.numSubN - 1;
                end
            end
            logRefresh_func(app,'Analysis Finished');
            app.Result_Show();
            app.PreviewButton_2.Enable = 'on';
            app.SaveResultButton.Enable = 'on';
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            clear all;
            close all;
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            % t1 = load('E:\IMG_暂存\testtif\TrainingData\data_table.mat');
            % app.UITable.Data = t1.dataTable;
            % 弹出文件浏览器选择文件路径和文件名
            DeSaveName = app.file_name;
            dotIndex = strfind(DeSaveName, '.');
            part1 = DeSaveName(1:dotIndex(1)-1);  % '.' 之前的部分
            [file, path] = uiputfile('*.*','Save Results',part1);

            % 检查用户是否选择了文件
            if ischar(file) && ischar(path)
                ResDataTableName = fullfile(path, strcat(file,'.xlsx'));
                ResMatName = fullfile(path, strcat(file,'.mat'));
                % 进行保存操作，比如保存图像
                % 假设 app.ImageData 是您想保存的图像数据
                writetable(app.ResDataTable,ResDataTableName);
                save(ResMatName,'app.VoCAT_Data');
                % 显示保存成功的消息
                msgbox('File saved successfully!', 'Success');
            else
                % 用户取消了操作
                msgbox('Save operation was canceled.');
            end
        end

        % Menu selected function: SkeletonizationMenu
        function SkeletonizationMenuSelected(app, event)
            [h,w,l] = size(app.VoCAT_Data.Correctbw);
            [x_sk,y_sk,z_sk]=ind2sub([h,w,l],find(app.VoCAT_Data.skel.sk));
            [x_ep,y_ep,z_ep]=ind2sub([h,w,l],find(app.VoCAT_Data.skel.bp));
            [x_bp,y_bp,z_bp]=ind2sub([h,w,l],find(app.VoCAT_Data.skel.ep));

            figure('Name','3D Discrete skeleton');
            plot3(x_sk,y_sk,z_sk,'square','Markersize',2,'MarkerFaceColor',[1 1 1],...
                'Color',[0 0 1]);
            hold on
            scatter3(x_ep,y_ep,z_ep,'filled','CData',[87 192 255]./255);
            scatter3(x_bp,y_bp,z_bp,'filled','CData',[255 0 0]./255);
            hold off
            %set(gca,'Color',[0.2 0.2 0.2]);
            ca = gca;
            ca.Title.String = 'Skeleton';
            ca.XAxis.Visible = 'off';
            ca.YAxis.Visible = 'off';
            ca.ZAxis.Visible = 'off';
            daspect([1 1 1]);
            view(3);
            xlim([0 size(app.VoCAT_Data.Correctbw,1)]);
            ylim([0 size(app.VoCAT_Data.Correctbw,2)]);
        end

        % Menu selected function: HistogramsMenu
        function HistogramsMenuSelected(app, event)
            % Length
            figure('Name','Histgrams: mesurement distribution for different metrics');
            subplot(2,2,1);
            histogram(app.VoCAT_Data.branchdata.Len(:),'FaceColor','r','Normalization','probability');
            xline(mean(app.VoCAT_Data.branchdata.Len(:)),'--r',{string(mean(app.VoCAT_Data.branchdata.Len(:)))});
            xlim([0 150]);
            title('Length');
            xlabel('Length [\ μm]');
            ylabel('Occurrences [%]');
            % Tortuosity
            subplot(2,2,2);
            histogram(app.VoCAT_Data.branchdata.Tort_new(:),'FaceColor','b','Normalization','probability');
            xline(mean(app.VoCAT_Data.branchdata.Tort_new(:)),'--r',{string(mean(app.VoCAT_Data.branchdata.Tort_new(:)))});
            xlim([0 1]);ylim([0 0.5]);
            xlabel('Tortuosity [adim.]');
            ylabel('Occurrences');
            title('Tortuosity');
            % Radius
            subplot(2,2,3);
            histogram(app.VoCAT_Data.branchdata.Rad(~isnan(app.VoCAT_Data.branchdata.Rad)),'FaceColor','g',...
                'Normalization','probability');
            xline(mean(app.VoCAT_Data.branchdata.Rad(~isnan(app.VoCAT_Data.branchdata.Rad))),'--r',{string(mean(app.VoCAT_Data.branchdata.Rad(:)))});
            xlim([0 250]);
            xlabel('Radius [\ μm]');
            ylabel('Occurrences [%]');
            title('Radius');
            % Eccentricity
            subplot(2,2,4);
            histogram(app.VoCAT_Data.branchdata.Eccent(:),'FaceColor',[234,179,10]./255,...
                'Normalization','probability');
            axis tight
            title('Eccentricity');
            xlim([0.5 1]);ylim([0 0.2]);
            xline(mean(app.VoCAT_Data.branchdata.Eccent(:)),'--r',{string(mean(app.VoCAT_Data.branchdata.Eccent(:)))});
            xlabel('Eccentricity [adim.]');
            ylabel('Occurrences [%]');
        end

        % Menu selected function: TortuosityMenu
        function TortuosityMenuSelected(app, event)
            % The skeleton has been redrawn, but this time the color of each branch depends on its tortuosity
            figure('Name','Tortuosity branch-wise')
            cm = colormap(jet);
            for b = 1:app.tot_branches
                currcolor_idx = 1+round((app.VoCAT_Data.branchdata.Tort_new(b)-min(app.VoCAT_Data.branchdata.Tort_new(:)))...
                    /max(app.VoCAT_Data.branchdata.Tort(:))*255);
                plot3(app.VoCAT_Data.branchdata.xPath{b}, app.VoCAT_Data.branchdata.yPath{b}, app.VoCAT_Data.branchdata.zPath{b},...
                    'Color', cm(currcolor_idx,:),'LineWidth',3);
                hold on
                scatter3(app.VoCAT_Data.branchdata.From(b,1), app.VoCAT_Data.branchdata.From(b,2), app.VoCAT_Data.branchdata.From(b,3),'o',...
                    'filled','MarkerFaceColor', [1 1 1]);
                scatter3(app.VoCAT_Data.branchdata.To(b,1), app.VoCAT_Data.branchdata.To(b,2), app.VoCAT_Data.branchdata.To(b,3),'o',...
                    'filled','MarkerFaceColor', [1 1 1]);
            end
            hold off
            set(gca,'CLim',[min(app.VoCAT_Data.branchdata.Tort_new(:)) max(app.VoCAT_Data.branchdata.Tort_new(:))]);
            set(gca,'Color',[0.2 0.2 0.2]);
            colorbar
            title('Tortuosity');
            daspect([1 1 1]);
            view(3);
            xlim([0 size(app.VoCAT_Data.Correctbw,1)]);
            ylim([0 size(app.VoCAT_Data.Correctbw,2)]);
        end

        % Menu selected function: RadiusMenu
        function RadiusMenuSelected(app, event)
            % The skeleton has been redrawn, but this time the color of each branch
            % depends on its radius
            figure('Name','Radius branch-wise')
            cool = colormap(jet);
            for b = 1:app.tot_branches
                currcolor_idx = ceil(app.VoCAT_Data.branchdata.Rad(b)/max(app.VoCAT_Data.branchdata.Rad(:))*256);
                plot3(app.VoCAT_Data.branchdata.xPath{b}, app.VoCAT_Data.branchdata.yPath{b}, app.VoCAT_Data.branchdata.zPath{b},...
                    'Color', cool(currcolor_idx,:),'LineWidth',3);
                hold on
                scatter3(app.VoCAT_Data.branchdata.From(b,1), app.VoCAT_Data.branchdata.From(b,2), app.VoCAT_Data.branchdata.From(b,3),'o',...
                    'filled','MarkerFaceColor', [1 1 1]);
                scatter3(app.VoCAT_Data.branchdata.To(b,1), app.VoCAT_Data.branchdata.To(b,2), app.VoCAT_Data.branchdata.To(b,3),'o',...
                    'filled','MarkerFaceColor', [1 1 1]);
            end
            hold off
            set(gca,'CLim',[min(app.VoCAT_Data.branchdata.Rad(:)) max(app.VoCAT_Data.branchdata.Rad(:))]);
            set(gca,'Color',[0.2 0.2 0.2]);
            colorbar;
            title('Radius');
            daspect([1 1 1]);
            view(3);
            xlim([0 size(app.VoCAT_Data.Correctbw,1)]);
            ylim([0 size(app.VoCAT_Data.Correctbw,2)]);
        end

        % Menu selected function: LengthMenu
        function LengthMenuSelected(app, event)
            %The skeleton has been redrawn, but this time the color of each branch
            %depends on its length
            figure('Name','Length branch-wise');
            cool = colormap(jet);
            for b = 1:app.tot_branches
                currcolor_idx = ceil(app.VoCAT_Data.branchdata.Len(b)/max(app.VoCAT_Data.branchdata.Len(:))*256);
                plot3(app.VoCAT_Data.branchdata.xPath{b}, app.VoCAT_Data.branchdata.yPath{b}, app.VoCAT_Data.branchdata.zPath{b},...
                    'Color', cool(currcolor_idx,:),'LineWidth',3);
                hold on
                scatter3(app.VoCAT_Data.branchdata.From(b,1), app.VoCAT_Data.branchdata.From(b,2), app.VoCAT_Data.branchdata.From(b,3),'o',...
                    'filled','MarkerFaceColor', [1 1 1]);
                scatter3(app.VoCAT_Data.branchdata.To(b,1), app.VoCAT_Data.branchdata.To(b,2), app.VoCAT_Data.branchdata.To(b,3),'o',...
                    'filled','MarkerFaceColor', [1 1 1]);
            end
            hold off
            set(gca,'CLim',[min(app.VoCAT_Data.branchdata.Len(:)) max(app.VoCAT_Data.branchdata.Len(:))]);
            set(gca,'Color',[0.2 0.2 0.2]);
            colorbar;
            title('Length');
            daspect([1 1 1]);
            view(3);
            xlim([0 size(app.VoCAT_Data.Correctbw,1)]);
            ylim([0 size(app.VoCAT_Data.Correctbw,2)]);
        end

        % Menu selected function: EccentricityMenu
        function EccentricityMenuSelected(app, event)
            % The skeleton has been redrawn, but this time the color of each branch depends on its eccenticity
            figure('Name','Eccenticity branch-wise');
            cool = colormap(jet);
            for b = 1:app.tot_branches
                currcolor_idx = ceil(app.VoCAT_Data.branchdata.Eccent(b)/max(app.VoCAT_Data.branchdata.Eccent(:))*256);
                plot3(app.VoCAT_Data.branchdata.xPath{b}, app.VoCAT_Data.branchdata.yPath{b}, app.VoCAT_Data.branchdata.zPath{b},...
                    'Color', cool(currcolor_idx,:),'LineWidth',3);
                hold on
                scatter3(app.VoCAT_Data.branchdata.From(b,1), app.VoCAT_Data.branchdata.From(b,2), app.VoCAT_Data.branchdata.From(b,3),'o',...
                    'filled','MarkerFaceColor', [1 1 1]);
                scatter3(app.VoCAT_Data.branchdata.To(b,1), app.VoCAT_Data.branchdata.To(b,2), app.VoCAT_Data.branchdata.To(b,3),'o',...
                    'filled','MarkerFaceColor', [1 1 1]);
            end
            hold off
            set(gca,'CLim',[min(app.VoCAT_Data.branchdata.Eccent(:)) max(app.VoCAT_Data.branchdata.Eccent(:))]);
            set(gca,'Color',[0.2 0.2 0.2]);
            colorbar;
            title('Eccentricity');
            daspect([1 1 1]);
            view(3);
            xlim([0 size(app.VoCAT_Data.Correctbw,1)]);
            ylim([0 size(app.VoCAT_Data.Correctbw,2)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VoCATUIFigure = uifigure('Visible', 'off');
            app.VoCATUIFigure.Name = 'VoCAT';
            app.VoCATUIFigure.Icon = fullfile(pathToMLAPP, 'VoCAT_Logo.png');
            app.VoCATUIFigure.CloseRequestFcn = createCallbackFcn(app, @VoCATUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.VoCATUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.DataLoadButton = uibutton(app.VoCATUIFigure, 'push');
            app.DataLoadButton.ButtonPushedFcn = createCallbackFcn(app, @DataLoadButtonPushed, true);
            app.DataLoadButton.Text = 'Data Load';
            app.LogTextAreaLabel = uilabel(app.VoCATUIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.VoCATUIFigure);
            app.ScalingperPixelEditFieldLabel = uilabel(app.VoCATUIFigure);
            app.ScalingperPixelEditFieldLabel.Text = 'Scaling (per Pixel)';
            app.ScalingperPixelEditField = uieditfield(app.VoCATUIFigure, 'numeric');
            app.ScalingperPixelEditField.Value = 1.66;
            app.DownsamplingFactorEditFieldLabel = uilabel(app.VoCATUIFigure);
            app.DownsamplingFactorEditFieldLabel.Text = 'Downsampling Factor';
            app.DownsamplingFactorEditField = uieditfield(app.VoCATUIFigure, 'numeric');
            app.DownsamplingFactorEditField.Value = 1;
            app.SmoothingRepetitionsEditFieldLabel = uilabel(app.VoCATUIFigure);
            app.SmoothingRepetitionsEditFieldLabel.Text = 'Smoothing Repetitions';
            app.SmoothingRepetitionsEditField = uieditfield(app.VoCATUIFigure, 'numeric');
            app.SmoothingRepetitionsEditField.Value = 3;
            app.RadiusPrecisionEditFieldLabel = uilabel(app.VoCATUIFigure);
            app.RadiusPrecisionEditFieldLabel.Text = 'Radius Precision';
            app.RadiusPrecisionEditField = uieditfield(app.VoCATUIFigure, 'numeric');
            app.RadiusPrecisionEditField.Value = 10;
            app.PointsPerBranchEditFieldLabel = uilabel(app.VoCATUIFigure);
            app.PointsPerBranchEditFieldLabel.Text = 'Points Per Branch';
            app.PointsPerBranchEditField = uieditfield(app.VoCATUIFigure, 'numeric');
            app.PointsPerBranchEditField.Value = 12;
            app.LengthThresholdEditFieldLabel = uilabel(app.VoCATUIFigure);
            app.LengthThresholdEditFieldLabel.Text = 'Length Threshold';
            app.LengthThresholdEditField = uieditfield(app.VoCATUIFigure, 'numeric');
            app.LengthThresholdEditField.Value = 15;
            app.SaveTextEditFieldLabel = uilabel(app.VoCATUIFigure);
            app.SaveTextEditFieldLabel.Text = 'Save Text';
            app.SaveTextEditField = uieditfield(app.VoCATUIFigure, 'text');
            app.SaveTextEditField.Value = 'File type';
            app.ScalingperPixelEditField_2 = uieditfield(app.VoCATUIFigure, 'numeric');
            app.ScalingperPixelEditField_2.Value = 1.66;
            app.ScalingperPixelEditField_3 = uieditfield(app.VoCATUIFigure, 'numeric');
            app.ScalingperPixelEditField_3.Value = 3.22;
            app.mLabel = uilabel(app.VoCATUIFigure);
            app.mLabel.Text = 'μm';
            app.ParametersettingLabel = uilabel(app.VoCATUIFigure);
            app.ParametersettingLabel.Text = 'Parameter setting: ';
            app.SegmentationtoolDropDownLabel = uilabel(app.VoCATUIFigure);
            app.SegmentationtoolDropDownLabel.Text = 'Segmentation tool';
            app.SegmentationtoolDropDown = uidropdown(app.VoCATUIFigure);
            app.SegmentationtoolDropDown.Items = {'Deep learning', 'Active Contour'};
            app.SegmentationtoolDropDown.Value = 'Deep learning';
            app.SegmentButton = uibutton(app.VoCATUIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Enable = 'off';
            app.SegmentButton.Text = 'Segment';
            app.VesselAnalysisButton = uibutton(app.VoCATUIFigure, 'push');
            app.VesselAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @VesselAnalysisButtonPushed, true);
            app.VesselAnalysisButton.Enable = 'off';
            app.VesselAnalysisButton.Text = 'Vessel Analysis';
            app.PreviewButton = uibutton(app.VoCATUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Text = 'Preview';
            app.SegmentResultLabel = uilabel(app.VoCATUIFigure);
            app.SegmentResultLabel.Text = 'Segment Result';
            app.UITable = uitable(app.VoCATUIFigure);
            app.UITable.ColumnName = {'Filen name'; 'Radius'; 'Branchpoints'; 'Endpoints'; 'Connectivity Ratio'; 'Radius (REAVER)'; 'Length'; 'TORTUOSITY'; 'TORTUOSITY (New)'; 'ECCENTRICITY'; 'VolFrac'; 'ApproxAlat'; 'RealAlat'; 'Surface/Volume'; 'numSubN'; 'GoodValue'; 'NNZ (BW)'; 'NUMEL(BW)'; 'Fractal dimension'};
            app.UITable.RowName = {};
            app.SaveResultButton = uibutton(app.VoCATUIFigure, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Enable = 'off';
            app.SaveResultButton.Text = 'Save Result';
            app.AnalysisResultsFigureLabel = uilabel(app.VoCATUIFigure);
            app.AnalysisResultsFigureLabel.Text = 'Analysis Results Figure';
            app.PreviewButton_2 = uibutton(app.VoCATUIFigure, 'push');
            app.PreviewButton_2.Enable = 'off';
            app.PreviewButton_2.Text = 'Preview';
            app.AnalysisResultsAverageDataLabel = uilabel(app.VoCATUIFigure);
            app.AnalysisResultsAverageDataLabel.Text = 'Analysis Results Average Data';
            app.Image = uiimage(app.VoCATUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'VoCAT_Logo.png');
            app.ContextMenu = uicontextmenu(app.VoCATUIFigure);
            app.HistogramsMenu = uimenu(app.ContextMenu);
            app.HistogramsMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramsMenuSelected, true);
            app.HistogramsMenu.Text = 'Histograms';
            app.TortuosityMenu = uimenu(app.ContextMenu);
            app.TortuosityMenu.MenuSelectedFcn = createCallbackFcn(app, @TortuosityMenuSelected, true);
            app.TortuosityMenu.Text = 'Tortuosity';
            app.RadiusMenu = uimenu(app.ContextMenu);
            app.RadiusMenu.MenuSelectedFcn = createCallbackFcn(app, @RadiusMenuSelected, true);
            app.RadiusMenu.Text = 'Radius';
            app.LengthMenu = uimenu(app.ContextMenu);
            app.LengthMenu.MenuSelectedFcn = createCallbackFcn(app, @LengthMenuSelected, true);
            app.LengthMenu.Text = 'Length';
            app.EccentricityMenu = uimenu(app.ContextMenu);
            app.EccentricityMenu.MenuSelectedFcn = createCallbackFcn(app, @EccentricityMenuSelected, true);
            app.EccentricityMenu.Text = 'Eccentricity';
            app.SkeletonizationMenu = uimenu(app.ContextMenu);
            app.SkeletonizationMenu.MenuSelectedFcn = createCallbackFcn(app, @SkeletonizationMenuSelected, true);
            app.SkeletonizationMenu.Text = 'Skeletonization';
            % Assign app.ContextMenu
            app.PreviewButton_2.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.VoCATUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VoCAT_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoCATUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoCATUIFigure)
        end
    end
end